classdef dialog_box_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        txtImageDirectory            matlab.ui.control.Label
        txtOutputDirectory           matlab.ui.control.Label
        Cy3Threshold                 matlab.ui.control.EditField
        FITCthreshold                matlab.ui.control.EditField
        imageDirectory               matlab.ui.control.Button
        outputDirectory              matlab.ui.control.Button
        run                          matlab.ui.control.Button
        text5                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        text7                        matlab.ui.control.Label
        text8                        matlab.ui.control.Label
        ThresholdImageDropDownLabel  matlab.ui.control.Label
        ThresholdImageDropDown       matlab.ui.control.DropDown
        ListBoxLabel                 matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        ListBox_2Label               matlab.ui.control.Label
        ListBox_2                    matlab.ui.control.ListBox
        ListBox_3Label               matlab.ui.control.Label
        ListBox_3                    matlab.ui.control.ListBox
        ListBox_4Label               matlab.ui.control.Label
        ListBox_4                    matlab.ui.control.ListBox
        ListBox_5Label               matlab.ui.control.Label
        ListBox_5                    matlab.ui.control.ListBox
        ListBox_6Label               matlab.ui.control.Label
        ListBox_6                    matlab.ui.control.ListBox
        ListBox_7Label               matlab.ui.control.Label
        ListBox_7                    matlab.ui.control.ListBox
        ListBox_8Label               matlab.ui.control.Label
        ListBox_8                    matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dialog_box_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to dialog_box (see VARARGIN)
            
            % Choose default command line output for dialog_box
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            app.ListBoxLabel.Visible = 'off';
            app.ListBox.Visible = 'off';
            app.ListBox_2Label.Visible = 'off';
            app.ListBox_2.Visible = 'off';
            app.ListBox_3Label.Visible = 'off';
            app.ListBox_3.Visible = 'off';
            app.ListBox_4Label.Visible = 'off';
            app.ListBox_4.Visible = 'off';
            app.ListBox_5Label.Visible = 'off';
            app.ListBox_5.Visible = 'off';
            app.ListBox_6Label.Visible = 'off';
            app.ListBox_6.Visible = 'off';
            app.ListBox_7Label.Visible = 'off';
            app.ListBox_7.Visible = 'off';
            app.ListBox_8Label.Visible = 'off';
            app.ListBox_8.Visible = 'off';
            
            app.ThresholdImageDropDownLabel.Visible = 'off';
            app.ThresholdImageDropDown.Visible = 'off';
        end

        % Button pushed function: imageDirectory
        function imageDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to imageDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imgdir = uigetdir();
            set(handles.txtImageDirectory, 'string', imgdir);
            currentDir = pwd;
            cd(string(imgdir));
            images = extractfield(dir('**/*COMBO.tif'), 'name');
            for i = 1:8
                switch i
                    case 1
                        app.ListBoxLabel.Text = images{i};
                        app.ListBoxLabel.Visible = 'on';
                        app.ListBox.Visible = 'on';
                    case 2
                        app.ListBox_2Label.Text = images{i};
                        app.ListBox_2Label.Visible = 'on';
                        app.ListBox_2.Visible = 'on';
                    case 3
                        app.ListBox_3Label.Text = images{i};
                        app.ListBox_3Label.Visible = 'on';
                        app.ListBox_3.Visible = 'on';
                    case 4
                        app.ListBox_4Label.Text = images{i};
                        app.ListBox_4Label.Visible = 'on';
                        app.ListBox_4.Visible = 'on';
                    case 5
                        app.ListBox_5Label.Text = images{i};
                        app.ListBox_5Label.Visible = 'on';
                        app.ListBox_5.Visible = 'on';
                    case 6
                        app.ListBox_6Label.Text = images{i};
                        app.ListBox_6Label.Visible = 'on';
                        app.ListBox_6.Visible = 'on';
                    case 7
                        app.ListBox_7Label.Text = images{i};
                        app.ListBox_7Label.Visible = 'on';
                        app.ListBox_7.Visible = 'on';
                    case 8
                        app.ListBox_8Label.Text = images{i};
                        app.ListBox_8Label.Visible = 'on';
                        app.ListBox_8.Visible = 'on';
                end
                app.ThresholdImageDropDown.Items = [app.ThresholdImageDropDown.Items, images{i}];
                app.ThresholdImageDropDown.Visible = 'on';
                app.ThresholdImageDropDownLabel.Visible = 'on';
            end
            cd(currentDir);
        end

        % Button pushed function: outputDirectory
        function outputDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to outputDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outdir = uigetdir();
            set(handles.txtOutputDirectory, 'string', outdir);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagefolder = get(handles.txtImageDirectory, 'string');
            outfolder = get(handles.txtOutputDirectory, 'string');
            Cy3threshold = str2double(get(handles.Cy3Threshold, 'string'));
            FITCthreshold = str2double(get(handles.FITCthreshold, 'string'));
            if strcmp(imagefolder, 'Select Image Directory') || strcmp(outfolder, 'Select Data Output Directory') || isnan(Cy3threshold) || isnan(FITCthreshold)
                errordlg('All fields are required');
            else
                layer_list = {
                    'Analyze All Layers', '0';
                    'Exclude Nerve Fiber Layer', '1';
                    'Exclude Ganglion Cell Layer', '2';
                    'Exclude Inner Plexiform Layer', '3';
                    'Exclude Inner Nuclear Layer', '4';
                    'Exclude Outer Plexiform Layer', '5';
                    'Exclude Outer Nuclear Layer', '6';
                    };
                exclusion_data = {
                    app.ListBoxLabel.Text, char(app.ListBox.Value);
                    app.ListBox_2Label.Text, char(app.ListBox_2.Value);
                    app.ListBox_3Label.Text, char(app.ListBox_3.Value);
                    app.ListBox_4Label.Text, char(app.ListBox_4.Value);
                    app.ListBox_5Label.Text, char(app.ListBox_5.Value);
                    app.ListBox_6Label.Text, char(app.ListBox_6.Value);
                    app.ListBox_7Label.Text, char(app.ListBox_7.Value);
                    app.ListBox_8Label.Text, char(app.ListBox_8.Value);
                    };
                cross_section_staining_analysis(imagefolder, Cy3threshold, FITCthreshold, outfolder, exclusion_data, app.ThresholdImageDropDown.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Retinal Image Analyzer';
            app.figure1.Tag = 'figure1';
            app.txtImageDirectory = uilabel(app.figure1);
            app.txtImageDirectory.Tag = 'txtImageDirectory';
            app.txtImageDirectory.Text = 'Select Image Directory';
            app.txtOutputDirectory = uilabel(app.figure1);
            app.txtOutputDirectory.Tag = 'txtOutputDirectory';
            app.txtOutputDirectory.Text = 'Select Data Output Directory';
            app.Cy3Threshold = uieditfield(app.figure1, 'text');
            app.Cy3Threshold.Tag = 'Cy3Threshold';
            app.FITCthreshold = uieditfield(app.figure1, 'text');
            app.FITCthreshold.Tag = 'FITCthreshold';
            app.imageDirectory = uibutton(app.figure1, 'push');
            app.imageDirectory.ButtonPushedFcn = createCallbackFcn(app, @imageDirectory_Callback, true);
            app.imageDirectory.Tag = 'imageDirectory';
            app.imageDirectory.Text = 'Browse';
            app.outputDirectory = uibutton(app.figure1, 'push');
            app.outputDirectory.ButtonPushedFcn = createCallbackFcn(app, @outputDirectory_Callback, true);
            app.outputDirectory.Tag = 'outputDirectory';
            app.outputDirectory.Text = 'Browse';
            app.run = uibutton(app.figure1, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Cy3 Threshold';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'FITC Threshold';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Data Output Directory';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Image Directory';
            app.ThresholdImageDropDownLabel = uilabel(app.figure1);
            app.ThresholdImageDropDownLabel.Text = 'Threshold Image';
            app.ThresholdImageDropDown = uidropdown(app.figure1);
            app.ThresholdImageDropDown.Items = {};
            app.ThresholdImageDropDown.Value = {};
            app.ListBoxLabel = uilabel(app.figure1);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.figure1);
            app.ListBox.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'0'};
            app.ListBox_2Label = uilabel(app.figure1);
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.figure1);
            app.ListBox_2.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_2.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {'0'};
            app.ListBox_3Label = uilabel(app.figure1);
            app.ListBox_3Label.Text = 'List Box';
            app.ListBox_3 = uilistbox(app.figure1);
            app.ListBox_3.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_3.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'0'};
            app.ListBox_4Label = uilabel(app.figure1);
            app.ListBox_4Label.Text = 'List Box';
            app.ListBox_4 = uilistbox(app.figure1);
            app.ListBox_4.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_4.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'0'};
            app.ListBox_5Label = uilabel(app.figure1);
            app.ListBox_5Label.Text = 'List Box';
            app.ListBox_5 = uilistbox(app.figure1);
            app.ListBox_5.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_5.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_5.Multiselect = 'on';
            app.ListBox_5.Value = {'0'};
            app.ListBox_6Label = uilabel(app.figure1);
            app.ListBox_6Label.Text = 'List Box';
            app.ListBox_6 = uilistbox(app.figure1);
            app.ListBox_6.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_6.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_6.Multiselect = 'on';
            app.ListBox_6.Value = {'0'};
            app.ListBox_7Label = uilabel(app.figure1);
            app.ListBox_7Label.Text = 'List Box';
            app.ListBox_7 = uilistbox(app.figure1);
            app.ListBox_7.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_7.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_7.Multiselect = 'on';
            app.ListBox_7.Value = {'0'};
            app.ListBox_8Label = uilabel(app.figure1);
            app.ListBox_8Label.Text = 'List Box';
            app.ListBox_8 = uilistbox(app.figure1);
            app.ListBox_8.Items = {'Analyze All Layers', 'Exclude Nerve Fiber Layer', 'Exclude Ganglion Cell Layer', 'Exclude Inner Plexiform Layer', 'Exclude Inner Nuclear Layer', 'Exclude Outer Plexiform Layer', 'Exclude Outer Nuclear Layer'};
            app.ListBox_8.ItemsData = {'0', '1', '2', '3', '4', '5', '6'};
            app.ListBox_8.Multiselect = 'on';
            app.ListBox_8.Value = {'0'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_box_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)dialog_box_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end